classdef Pendant2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Joint1SliderLabel     matlab.ui.control.Label
        Joint1Slider          matlab.ui.control.Slider
        Joint2SliderLabel     matlab.ui.control.Label
        Joint2Slider          matlab.ui.control.Slider
        Joint3SliderLabel     matlab.ui.control.Label
        Joint3Slider          matlab.ui.control.Slider
        Joint4SliderLabel     matlab.ui.control.Label
        Joint4Slider          matlab.ui.control.Slider
        Joint5SliderLabel     matlab.ui.control.Label
        Joint5Slider          matlab.ui.control.Slider
        Joint6SliderLabel     matlab.ui.control.Label
        Joint6Slider          matlab.ui.control.Slider
        COMPortDropDownLabel  matlab.ui.control.Label
        COMPortDropDown       matlab.ui.control.DropDown
        SavePointButton       matlab.ui.control.Button
        ConnectButton         matlab.ui.control.Button
        HomeButton            matlab.ui.control.Button
        SpeedSpinnerLabel     matlab.ui.control.Label
        SpeedSpinner          matlab.ui.control.Spinner
        EditField             matlab.ui.control.EditField
        ClickheretousekeyboardcontrolLabel  matlab.ui.control.Label
        EmergencyStopButton   matlab.ui.control.Button
        ResetButton           matlab.ui.control.Button
    end

    
    properties (Access = private)
        robot = []; % Description
        position = [1500 1500 1500 1500 1500 1500];
        speed = [750 750 750 750 750 750];
        ESTOP=0;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            comports = seriallist;
            app.COMPortDropDown.Items = comports;
            app.COMPortDropDown.ItemsData = comports;
            app.Joint1Slider.Value = app.position(1);
            app.Joint2Slider.Value = app.position(2);
            app.Joint3Slider.Value = app.position(3);
            app.Joint4Slider.Value = app.position(4);
            app.Joint5Slider.Value = app.position(5);
            app.Joint6Slider.Value = app.position(6);
            app.SpeedSpinner.Limits = [1 5000];
            app.SpeedSpinner.Value = 750;
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            if isempty(app.COMPortDropDown.Value)
                return;
            else
                app.robot = RobotArm(app.COMPortDropDown.Value);
                app.position = [1500 1500 1500 1500 1500 1500];
                set(app.Joint1Slider, 'Value', app.position(1));
                set(app.Joint2Slider, 'Value', app.position(2));
                set(app.Joint3Slider, 'Value', app.position(3));
                set(app.Joint4Slider, 'Value', app.position(4));
                set(app.Joint5Slider, 'Value', app.position(5));
                set(app.Joint6Slider, 'Value', app.position(6));
                app.Joint1Slider.Enable=true
                app.Joint2Slider.Enable=true
                app.Joint3Slider.Enable=true
                app.Joint4Slider.Enable=true
                app.Joint5Slider.Enable=true
                app.Joint6Slider.Enable=true
                app.HomeButton.Enable=true
                
            end
        end

        % Button pushed function: SavePointButton
        function SavePointButtonPushed(app, event)
        disp("robot.move([0 1 2 3 4 5], ["+app.Joint1Slider.Value+" "+app.Joint2Slider.Value+" "+app.Joint3Slider.Value+" "+app.Joint4Slider.Value+" "+ app.Joint5Slider.Value+" "+app.Joint6Slider.Value+"]);");                                        
            app.EditField.Value="robot.move([0 1 2 3 4 5], ["+string(app.Joint1Slider.Value)+" "+string(app.Joint2Slider.Value)+" "+string(app.Joint3Slider.Value)+" "+string(app.Joint4Slider.Value)+" "+ string(app.Joint5Slider.Value)+" "+string(app.Joint6Slider.Value)+"]);"                           
        end

        % Button pushed function: HomeButton
        function HomeButtonPushed(app, event)
            app.position = [1500 1500 1500 1500 1500 1500];
            app.robot.move([0 1 2 3 4 5],  app.position, app.SpeedSpinner.Value);
            set(app.Joint1Slider, 'Value', app.position(1));
            set(app.Joint2Slider, 'Value', app.position(2));
            set(app.Joint3Slider, 'Value', app.position(3));
            set(app.Joint4Slider, 'Value', app.position(4));
            set(app.Joint5Slider, 'Value', app.position(5));
            set(app.Joint6Slider, 'Value', app.position(6));
        end

        % Value changed function: Joint2Slider
        function Joint2SliderValueChanged(app, event)
            value = app.Joint2Slider.Value;
            app.robot.move(1, value, app.SpeedSpinner.Value);
        end

        % Value changed function: Joint1Slider
        function Joint1SliderValueChanged(app, event)
            value = app.Joint1Slider.Value;
            app.robot.move(0, value, app.SpeedSpinner.Value);
        end

        % Value changed function: Joint3Slider
        function Joint3SliderValueChanged(app, event)
            value = app.Joint3Slider.Value;
            app.robot.move(2, value, app.SpeedSpinner.Value);
        end

        % Value changed function: Joint4Slider
        function Joint4SliderValueChanged(app, event)
            value = app.Joint4Slider.Value;
            app.robot.move(3, value, app.SpeedSpinner.Value);
        end

        % Value changed function: Joint5Slider
        function Joint5SliderValueChanged(app, event)
            value = app.Joint5Slider.Value;
            app.robot.move(4, value, app.SpeedSpinner.Value);
        end

        % Value changed function: Joint6Slider
        function Joint6SliderValueChanged(app, event)
            value = app.Joint6Slider.Value;
            app.robot.move(5, value, app.SpeedSpinner.Value);
        end

        % Value changed function: SpeedSpinner
        function SpeedSpinnerValueChanged(app, event)
            value = app.SpeedSpinner.Value;
            app.speed = value*ones(1,length(app.speed));
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            
        end

        % Key release function: UIFigure
        function UIFigureKeyRelease(app, event)
            key = event.Key;
            if strcmp(app.HomeButton.Enable,'off')==false && app.ESTOP==0
            switch key
            case '1'
                if app.Joint1Slider.Value<=2400
                    app.Joint1Slider.Value=app.Joint1Slider.Value+100;
                    value=app.Joint1Slider.Value;
                    app.robot.move(0,value, app.SpeedSpinner.Value);
                end
            case 'q'
                if app.Joint1Slider.Value>=600
                    app.Joint1Slider.Value=app.Joint1Slider.Value-100;
                    value=app.Joint1Slider.Value;
                    app.robot.move(0,value, app.SpeedSpinner.Value);
                end
            case 'a'
        	if app.Joint1Slider.Value<=2495
                    app.Joint1Slider.Value=app.Joint1Slider.Value+5;
                    value=app.Joint1Slider.Value;
                    app.robot.move(0,value, app.SpeedSpinner.Value);
                end
            case 'z'
        	if app.Joint1Slider.Value>=505
                    app.Joint1Slider.Value=app.Joint1Slider.Value-5;
                    value=app.Joint1Slider.Value;
                    app.robot.move(0,value, app.SpeedSpinner.Value);
                end
            case '2'
                if app.Joint2Slider.Value<=2400
                    app.Joint2Slider.Value=app.Joint2Slider.Value+100;
                    value=app.Joint2Slider.Value;
                    app.robot.move(1,value, app.SpeedSpinner.Value);
                end
            case 'w'
                if app.Joint2Slider.Value>=600
                    app.Joint2Slider.Value=app.Joint2Slider.Value-100;
                    value=app.Joint2Slider.Value;
                    app.robot.move(1,value, app.SpeedSpinner.Value);
                end
            case 's'
        	if app.Joint2Slider.Value<=2495
                    app.Joint2Slider.Value=app.Joint2Slider.Value+5;
                    value=app.Joint2Slider.Value;
                    app.robot.move(1,value, app.SpeedSpinner.Value);
                end
            case 'x'
        	if app.Joint2Slider.Value>=505
                    app.Joint2Slider.Value=app.Joint2Slider.Value-5;
                    value=app.Joint2Slider.Value;
                    app.robot.move(1,value, app.SpeedSpinner.Value);
                end
            case '3'
                if app.Joint3Slider.Value<=2400
                    app.Joint3Slider.Value=app.Joint3Slider.Value+100;
                    value=app.Joint3Slider.Value;
                    app.robot.move(2,value, app.SpeedSpinner.Value);
                end
            case 'e'
                if app.Joint3Slider.Value>=600
                    app.Joint3Slider.Value=app.Joint3Slider.Value-100;
                    value=app.Joint3Slider.Value;
                    app.robot.move(2,value, app.SpeedSpinner.Value);
                end
            case 'd'
        	if app.Joint3Slider.Value<=2495
                    app.Joint3Slider.Value=app.Joint3Slider.Value+5;
                    value=app.Joint3Slider.Value;
                    app.robot.move(2,value, app.SpeedSpinner.Value);
                end
            case 'c'
        	if app.Joint3Slider.Value>=505
                    app.Joint3Slider.Value=app.Joint3Slider.Value-5;
                    value=app.Joint3Slider.Value;
                    app.robot.move(2,value, app.SpeedSpinner.Value);
                end
            case '4'
                if app.Joint4Slider.Value<=2400
                    app.Joint4Slider.Value=app.Joint4Slider.Value+100;
                    value=app.Joint4Slider.Value;
                    app.robot.move(3,value, app.SpeedSpinner.Value);
                end
            case 'r'
                if app.Joint4Slider.Value>=600
                    app.Joint4Slider.Value=app.Joint4Slider.Value-100;
                    value=app.Joint4Slider.Value;
                    app.robot.move(3,value, app.SpeedSpinner.Value);
                end
            case 'f'
        	if app.Joint4Slider.Value<=2495
                    app.Joint4Slider.Value=app.Joint4Slider.Value+5;
                    value=app.Joint4Slider.Value;
                    app.robot.move(3,value, app.SpeedSpinner.Value);
                end
            case 'v'
        	if app.Joint4Slider.Value>=505
                    app.Joint4Slider.Value=app.Joint4Slider.Value-5;
                    value=app.Joint4Slider.Value;
                    app.robot.move(3,value, app.SpeedSpinner.Value);
                end
            case '5'
                if app.Joint5Slider.Value<=2400
                    app.Joint5Slider.Value=app.Joint5Slider.Value+100;
                    value=app.Joint5Slider.Value;
                    app.robot.move(4,value, app.SpeedSpinner.Value);
                end
            case 't'
                if app.Joint5Slider.Value>=600
                    app.Joint5Slider.Value=app.Joint5Slider.Value-100;
                    value=app.Joint5Slider.Value;
                    app.robot.move(4,value, app.SpeedSpinner.Value);
                end
            case 'g'
        	if app.Joint5Slider.Value<=2495
                    app.Joint5Slider.Value=app.Joint5Slider.Value+5;
                    value=app.Joint5Slider.Value;
                    app.robot.move(4,value, app.SpeedSpinner.Value);
                end
            case 'b'
        	if app.Joint5Slider.Value>=505
                    app.Joint5Slider.Value=app.Joint5Slider.Value-5;
                    value=app.Joint5Slider.Value;
                    app.robot.move(4,value, app.SpeedSpinner.Value);
                end
            case '6'
                if app.Joint6Slider.Value<=2400
                    app.Joint6Slider.Value=app.Joint6Slider.Value+100;
                    value=app.Joint6Slider.Value;
                    app.robot.move(5,value, app.SpeedSpinner.Value);
                end
            case 'y'
                if app.Joint6Slider.Value>=600
                    app.Joint6Slider.Value=app.Joint6Slider.Value-100;
                    value=app.Joint6Slider.Value;
                    app.robot.move(5,value, app.SpeedSpinner.Value);
                end
            case 'h'
        	if app.Joint6Slider.Value<=2495
                    app.Joint6Slider.Value=app.Joint6Slider.Value+5;
                    value=app.Joint6Slider.Value;
                    app.robot.move(5,value, app.SpeedSpinner.Value);
                end
            case 'n'
        	if app.Joint6Slider.Value>=505
                    app.Joint6Slider.Value=app.Joint6Slider.Value-5;
                    value=app.Joint6Slider.Value;
                    app.robot.move(5,value, app.SpeedSpinner.Value);
                end
            end
            end
            
        end

        % Button pushed function: EmergencyStopButton
        function EmergencyStopButtonPushed(app, event)
            app.ESTOP=1;
            clear app.robot
            app.Joint1Slider.Enable=false
            app.Joint2Slider.Enable=false
            app.Joint3Slider.Enable=false
            app.Joint4Slider.Enable=false
            app.Joint5Slider.Enable=false
            app.Joint6Slider.Enable=false
            app.HomeButton.Enable=false
            app.EmergencyStopButton.Enable=false
            app.ResetButton.Enable=true
            app.robot.stop()
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.ESTOP=0;
            app.EmergencyStopButton.Enable=true
            app.ResetButton.Enable=false
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIFigure.KeyReleaseFcn = createCallbackFcn(app, @UIFigureKeyRelease, true);
            app.Joint1SliderLabel = uilabel(app.UIFigure);
            app.Joint1SliderLabel.Enable = 'off';
            app.Joint1SliderLabel.Text = 'Joint 1';
            app.Joint1Slider = uislider(app.UIFigure);
            app.Joint1Slider.Limits = [500 2500];
            app.Joint1Slider.ValueChangedFcn = createCallbackFcn(app, @Joint1SliderValueChanged, true);
            app.Joint1Slider.Enable = 'off';
            app.Joint1Slider.Value = 1500;
            app.Joint2SliderLabel = uilabel(app.UIFigure);
            app.Joint2SliderLabel.Enable = 'off';
            app.Joint2SliderLabel.Text = 'Joint 2';
            app.Joint2Slider = uislider(app.UIFigure);
            app.Joint2Slider.Limits = [500 2500];
            app.Joint2Slider.ValueChangedFcn = createCallbackFcn(app, @Joint2SliderValueChanged, true);
            app.Joint2Slider.Enable = 'off';
            app.Joint2Slider.Value = 1500;
            app.Joint3SliderLabel = uilabel(app.UIFigure);
            app.Joint3SliderLabel.Enable = 'off';
            app.Joint3SliderLabel.Text = 'Joint 3';
            app.Joint3Slider = uislider(app.UIFigure);
            app.Joint3Slider.Limits = [500 2500];
            app.Joint3Slider.ValueChangedFcn = createCallbackFcn(app, @Joint3SliderValueChanged, true);
            app.Joint3Slider.Enable = 'off';
            app.Joint3Slider.Value = 1500;
            app.Joint4SliderLabel = uilabel(app.UIFigure);
            app.Joint4SliderLabel.Enable = 'off';
            app.Joint4SliderLabel.Text = 'Joint 4';
            app.Joint4Slider = uislider(app.UIFigure);
            app.Joint4Slider.Limits = [500 2500];
            app.Joint4Slider.ValueChangedFcn = createCallbackFcn(app, @Joint4SliderValueChanged, true);
            app.Joint4Slider.Enable = 'off';
            app.Joint4Slider.Value = 1500;
            app.Joint5SliderLabel = uilabel(app.UIFigure);
            app.Joint5SliderLabel.Enable = 'off';
            app.Joint5SliderLabel.Text = 'Joint 5';
            app.Joint5Slider = uislider(app.UIFigure);
            app.Joint5Slider.Limits = [500 2500];
            app.Joint5Slider.ValueChangedFcn = createCallbackFcn(app, @Joint5SliderValueChanged, true);
            app.Joint5Slider.Enable = 'off';
            app.Joint5Slider.Value = 1500;
            app.Joint6SliderLabel = uilabel(app.UIFigure);
            app.Joint6SliderLabel.Enable = 'off';
            app.Joint6SliderLabel.Text = 'Joint 6';
            app.Joint6Slider = uislider(app.UIFigure);
            app.Joint6Slider.Limits = [500 2500];
            app.Joint6Slider.ValueChangedFcn = createCallbackFcn(app, @Joint6SliderValueChanged, true);
            app.Joint6Slider.Enable = 'off';
            app.Joint6Slider.Value = 1500;
            app.COMPortDropDownLabel = uilabel(app.UIFigure);
            app.COMPortDropDownLabel.Text = 'COM Port';
            app.COMPortDropDown = uidropdown(app.UIFigure);
            app.SavePointButton = uibutton(app.UIFigure, 'push');
            app.SavePointButton.ButtonPushedFcn = createCallbackFcn(app, @SavePointButtonPushed, true);
            app.SavePointButton.Text = 'Save Point';
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.HomeButton = uibutton(app.UIFigure, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButton.Enable = 'off';
            app.HomeButton.Text = 'Home';
            app.SpeedSpinnerLabel = uilabel(app.UIFigure);
            app.SpeedSpinnerLabel.Text = 'Speed';
            app.SpeedSpinner = uispinner(app.UIFigure);
            app.SpeedSpinner.ValueChangedFcn = createCallbackFcn(app, @SpeedSpinnerValueChanged, true);
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Editable = 'off';
            app.ClickheretousekeyboardcontrolLabel = uilabel(app.UIFigure);
            app.ClickheretousekeyboardcontrolLabel.Text = 'Click here to use keyboard control';
            app.EmergencyStopButton = uibutton(app.UIFigure, 'push');
            app.EmergencyStopButton.ButtonPushedFcn = createCallbackFcn(app, @EmergencyStopButtonPushed, true);
            app.EmergencyStopButton.Text = 'Emergency Stop';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Enable = 'off';
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Pendant2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end